classdef electric_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interfaz            matlab.ui.Figure
        reset               matlab.ui.control.Button
        Image               matlab.ui.control.Image
        Calcular            matlab.ui.control.Button
        numero_cargas       matlab.ui.control.NumericEditField
        NmerodecargasLabel  matlab.ui.control.Label
        tabla_cargas        matlab.ui.control.Table
    end

    properties (Access = private)
        Tabla_respaldo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tabla_cargas.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.tabla_cargas.Data = cell(1, 3);
        end

        % Cell edit callback: tabla_cargas
        function tabla_cargasCellEdit(app, event)

        end

        % Value changed function: numero_cargas
        function numero_cargasValueChanged(app, event)
            %Funcion que se activa cuando el text input que corresponde al
            %valor de numero de cargas cambia. Este valor es definido por
            %el usuario.            
            cantidad_cargas = app.numero_cargas.Value;
            %tamaño del cuadro de cargas y posicion
            [m, n] = size(app.tabla_cargas.Data);
            
            %Se respalda el contenido de toda la tabla para ingresar nuevas
            %cargas o eliminar las restantes
            app.Tabla_respaldo = app.tabla_cargas.Data;
            
            %Se evalua si se desea agregar cargas
            if cantidad_cargas < m
                d = cell(cantidad_cargas, n);
                app.Tabla_respaldo = app.tabla_cargas.Data(1 : cantidad_cargas, 1 : n);
                d(1 : cantidad_cargas, 1 : n) = app.Tabla_respaldo;
                app.tabla_cargas.Data = d;
            
            else 
                d = cell(cantidad_cargas, n);
                d(1 : m, 1 : n) = app.Tabla_respaldo;
                app.tabla_cargas.Data = d;
            end            
        end

        % Button pushed function: Calcular
        function CalcularButtonPushed(app, event)
        %Campo eléctrico por superposición
        close all
        N = 20;
        [m, ~] = size(app.tabla_cargas.Data);
        dx = abs(max(cell2mat(app.tabla_cargas.Data(:, 2)))) + abs(min(cell2mat(app.tabla_cargas.Data(:, 2)))) / 2;
        dy = abs(max(cell2mat(app.tabla_cargas.Data(:, 3)))) + abs(min(cell2mat(app.tabla_cargas.Data(:, 3)))) / 2; 
        minX = min(cell2mat(app.tabla_cargas.Data(:, 2))) - dx ; maxX = max(cell2mat(app.tabla_cargas.Data(:, 2))) + dx;
        minY = min(cell2mat(app.tabla_cargas.Data(:, 3))) - dy ; maxY = max(cell2mat(app.tabla_cargas.Data(:, 3))) + dy;
        x = linspace(minX, maxX, N);
        y = linspace(minY, maxY, N);
        [xG, yG]= meshgrid(x, y);
        
        Eo = 8.854187817e-12;
        k = 1 / (4 * pi * Eo);
        Ex = 0;
        Ey = 0;
        for i = 1 : m
            Qn = cell2mat(app.tabla_cargas.Data(i, 1));
            xC = cell2mat(app.tabla_cargas.Data(i, 2));
            yC = cell2mat(app.tabla_cargas.Data(i, 3));
            Rx = xG - xC;
            Ry = yG - yC;
            R = sqrt(Rx .^ 2 + Ry .^ 2) .^3;
            Ex = Ex + k .* Qn .* Rx ./ R;
            Ey = Ey + k .* Qn .* Ry ./ R;
        end 
        E = sqrt(Ex .^ 2 + Ey .^ 2);
        
        %componentes x
        u = Ex ./E;
        %componentes y
        v = Ey ./E;


        %Potencial eléctrico por superposición
            function V= funcionpotencial(q, r)
                V = k * q / r;
            end
        V = zeros(length(x), length(y));
        

       qq = cell2mat(app.tabla_cargas.Data(:, 1));
       qx = cell2mat(app.tabla_cargas.Data(:, 2));
       qy = cell2mat(app.tabla_cargas.Data(:, 3));
       
        
        for ii = 1 : length(x)
            for jj = 1 : length(y)
                for kk = 1 : length(qq)
                    r = sqrt((x(ii) - qx(kk)).^ 2 + (y(jj) - qy(kk)) .^ 2);
                    V(ii, jj) = V(ii, jj) + funcionpotencial(qq(kk), r); 
                end
            end
        end
        
        
        figure("Name", 'Campo eléctrico | Potencial eléctrico', "WindowState","maximized")
        
        subplot(3, 19, [1, 2, 3, 4, 5, 6, 20, 21, 22, 23, 24, 25, 39, 40, 41, 42, 43, 44])
        streamslice(xG, yG, u, v );
        title('Lineas de campo eléctrico')
        
        subplot(3, 19, [8, 9, 10, 11, 12, 13, 27, 28, 29, 30, 31, 32, 46, 47, 48, 49, 50, 51])
        surf(x, y, V);
        shading interp
        colormap jet
        title('Mapa de calor de potencial')
        
        
        subplot(3, 19, [15, 16, 17, 18, 19, 34, 35, 36, 37, 38, 53, 54, 55, 56, 57])
        contourf(V, 10);
        shading interp
        colormap jet
        title('Campos equipotenciales')
        
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
                app.tabla_cargas.Data = cell(app.numero_cargas.Value, 3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interfaz = uifigure('Visible', 'off');
            app.interfaz.Name = 'Ingreso de cargas';
            app.interfaz.Icon = 'ico.png';
            app.tabla_cargas = uitable(app.interfaz);
            app.tabla_cargas.ColumnName = {'Carga'; 'x'; 'y'};
            app.tabla_cargas.ColumnEditable = true;
            app.tabla_cargas.CellEditCallback = createCallbackFcn(app, @tabla_cargasCellEdit, true);
            app.NmerodecargasLabel = uilabel(app.interfaz);
            app.NmerodecargasLabel.Text = 'Número de cargas';
            app.numero_cargas = uieditfield(app.interfaz, 'numeric');
            app.numero_cargas.Limits = [1 Inf];
            app.numero_cargas.RoundFractionalValues = 'on';
            app.numero_cargas.ValueChangedFcn = createCallbackFcn(app, @numero_cargasValueChanged, true);
            app.numero_cargas.Value = 1;
            app.Calcular = uibutton(app.interfaz, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.Calcular.Text = 'Graficar';
            app.Image = uiimage(app.interfaz);
            app.Image.ImageSource = 'escudo-inge-03.png';
            app.reset = uibutton(app.interfaz, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'RESET';
            % Show the figure after all components are created
            app.interfaz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electric_field

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interfaz)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interfaz)
        end
    end
end